classdef neuro_cbs_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CBSGraphicalInterfaceUIFigure  matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        DoClassificationCheckBox       matlab.ui.control.CheckBox
        SavePlotsCheckBox              matlab.ui.control.CheckBox
        Bank0Button                    matlab.ui.control.Button
        Bank1Button                    matlab.ui.control.Button
        Bank2Button                    matlab.ui.control.Button
        ImroButton                     matlab.ui.control.Button
        PlotSaveDirButton              matlab.ui.control.Button
        Bank0FileEditFieldLabel        matlab.ui.control.Label
        Bank0FileEditField             matlab.ui.control.EditField
        Bank1FileEditFieldLabel        matlab.ui.control.Label
        Bank1FileEditField             matlab.ui.control.EditField
        ProbeTypeDropDownLabel         matlab.ui.control.Label
        ProbeTypeDropDown              matlab.ui.control.DropDown
        Bank2FileEditFieldLabel        matlab.ui.control.Label
        Bank2FileEditField             matlab.ui.control.EditField
        OutputimroFileLabel            matlab.ui.control.Label
        OutputimroFileEditField        matlab.ui.control.EditField
        AlgorithmDropDownLabel         matlab.ui.control.Label
        AlgorithmDropDown              matlab.ui.control.DropDown
        NumSpikesUnitEditFieldLabel    matlab.ui.control.Label
        NumSpikesUnitEditField         matlab.ui.control.NumericEditField
        PlotSaveDirEditFieldLabel      matlab.ui.control.Label
        PlotSaveDirEditField           matlab.ui.control.EditField
        InitMethodDropDownLabel        matlab.ui.control.Label
        InitMethodDropDown             matlab.ui.control.DropDown
        Label                          matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        RUNButton                      matlab.ui.control.Button
        imroValid                      matlab.ui.control.Label
        HelpButton                     matlab.ui.control.Button
    end

    
    properties (Access = private)
        raw_files = {}; % file list, cell array
        raw_files_valid = false(1, 3);
        imro_file_path = [];
        imro_specified = false;
        
        plot_save_dir = [];
        plot_save_dir_specified = false;
        
        bank_models
    end
    
    methods (Access = private)
        function good = check_rec(app, ap_bin, bank)
            good = false;
         
                [is_kilosorted, is_all_one_bank, actual_bank] = check_neuropix_bank_rec(ap_bin);
                
                if actual_bank == bank && is_all_one_bank && is_kilosorted
                    % good to go
                    good = true;
                else
                    % invalid
                    if actual_bank ~= bank
                        warning('The chosen recording must have all channels (or as many as possible) assigned to bank %d', bank);
                    end
                    
                    if ~is_kilosorted
                        warning('The recording must be spike sorted before running CBS');
                    end         
                    good = false;
                end
            
            
        end
        
        
        function good = check_deps(app)
            kilosort_exists = exist('kilosort', 'file');
            if ~kilosort_exists
                warning('Kilosort2 package not found in your path. Clone https://github.com/MouseLand/Kilosort2');
            end
            spikes_exists = exist('readSpikeGLXmeta', 'file');
            if ~spikes_exists
                warning('Spikes package not found in your path. Clone https://github.com/cortex-lab/spikes');
            end
            npy_exists = exist('readNPY', 'file');
            
            if ~npy_exists
                warning('npy_matlab package not found in your path. Clone https://github.com/kwikteam/npy-matlab');
            end
            
            cbs_exists = exist('greedy_search_cbs', 'file');
            if ~cbs_exists
                warning('neuro_cbs or its subdirectories not in path');
            end
            
            good = kilosort_exists && spikes_exists && npy_exists && cbs_exists;
            
        end
        
        function sigint_handler(app)
            set(app.RUNButton, 'Text', 'Run');
            set(app.RUNButton, 'Enable', 'on');
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            good = app.check_deps();
            if ~good
                warning('Dependencies missing. Exiting app.');
                app.delete();    
            end
        end

        % Button pushed function: Bank0Button
        function Bank0ButtonPushed(app, event)
        
            old_val = app.Bank0FileEditField.Value;
            
            [file, path] = uigetfile('*.ap.bin', 'Select bank 0 recording file');
            
            if ~file
                fullpath = old_val;    
            else
                fullpath = fullfile(path, file);
            end
            
            app.raw_files{1} = fullpath;
            app.Bank0FileEditField.Value = fullpath;
            
            good = app.check_rec(fullpath, 0);
            if good
                app.Label.Text = 'OK';
                app.Label.FontColor = 'green';
            else
                app.Label.Text = 'no good';
                app.Label.FontColor = 'black';
            end
            app.raw_files_valid(1) = good;
            
            if all(app.raw_files_valid) && app.imro_specified
                app.RUNButton.Enable = true;
            else
                app.RUNButton.Enable = false;
            end
        
        end

        % Button pushed function: Bank1Button
        function Bank1ButtonPushed(app, event)
            
            old_val = app.Bank1FileEditField.Value;
            
            [file, path] = uigetfile('*.ap.bin', 'Select bank 1 recording file');
            if ~file
                fullpath = old_val;    
            else
                fullpath = fullfile(path, file);
            end
            
            app.raw_files{2} = fullpath;
            
            app.Bank1FileEditField.Value = fullpath;
            good = app.check_rec(fullpath, 1);
            if good
                app.Label_2.Text = 'OK';
                app.Label_2.FontColor = 'green';
            else
                app.Label_2.Text = 'no good';
                app.Label_2.FontColor = 'black';
            end
            app.raw_files_valid(2) = good;
            
            if all(app.raw_files_valid) && app.imro_specified
                app.RUNButton.Enable = true;
            else
                app.RUNButton.Enable = false;
            end
        end

        % Button pushed function: Bank2Button
        function Bank2ButtonPushed(app, event)
            
            old_val = app.Bank2FileEditField.Value;
            
            [file, path] = uigetfile('*.ap.bin', 'Select bank 2 recording file');
            
            if ~file
                fullpath = old_val;    
            else
                fullpath = fullfile(path, file);
            end
            
            app.raw_files{3} = fullpath;
            
            app.Bank2FileEditField.Value = fullpath;
            good = app.check_rec(fullpath, 2);
            if good
                app.Label_3.Text = 'OK';
                app.Label_3.FontColor = 'green';
            else
                app.Label_3.Text = 'no good';
                app.Label_3.FontColor = 'black';
            end
            app.raw_files_valid(3) = good;
            
            if all(app.raw_files_valid) && app.imro_specified
                app.RUNButton.Enable = true;
            else
                app.RUNButton.Enable = false;
            end
            
        end

        % Value changed function: Bank0FileEditField
        function Bank0FileEditFieldValueChanged(app, event)
            value = app.Bank0FileEditField.Value;
            good = app.check_rec(value, 0);
            
            if good
                app.Label.Text = 'OK';
                app.Label.FontColor = 'green';
            else
                app.Label.Text = 'no good';
                app.Label.FontColor = 'black';
            end
            app.raw_files_valid(1) = good;
            if all(app.raw_files_valid) && app.imro_specified
                app.RUNButton.Enable = true;
            else
                app.RUNButton.Enable = false;
            end
        end

        % Value changed function: Bank1FileEditField
        function Bank1FileEditFieldValueChanged(app, event)
            value = app.Bank1FileEditField.Value;
            good = app.check_rec(value, 1);
            if good
                app.Label_2.Text = 'OK';
                app.Label_2.FontColor = 'green';
            else
                app.Label_2.Text = 'no good';
                app.Label_2.FontColor = 'black';
            end

            app.raw_files_valid(2) = good;
            if all(app.raw_files_valid) && app.imro_specified
                app.RUNButton.Enable = true;
            else
                app.RUNButton.Enable = false;
            end
        end

        % Value changed function: Bank2FileEditField
        function Bank2FileEditFieldValueChanged(app, event)
            value = app.Bank2FileEditField.Value;
            good = app.check_rec(value, 2);
            if good
                app.Label_3.Text = 'OK';
                app.Label_3.FontColor = 'green';
            else
                app.Label_3.Text = 'no good';
                app.Label_3.FontColor = 'black';
            end

            app.raw_files_valid(3) = good;
            if all(app.raw_files_valid) && app.imro_specified
                app.RUNButton.Enable = true;
            else
                app.RUNButton.Enable = false;
            end
            
        end

        % Button pushed function: ImroButton
        function ImroButtonPushed(app, event)
            
            old_val = app.OutputimroFileEditField.Value;
            
            [file, path] = uiputfile('*.imro', 'Save output .imro file as');
            
            if ~file
                fullpath = old_val;    
            else
                fullpath = fullfile(path, file);
            end
            
            [path, ~, ~] = fileparts(fullpath);
                
            if exist(path, 'dir')
              
                app.imro_file_path = fullpath;
                
                app.imro_specified = true;
                
                app.OutputimroFileEditField.Value = fullpath;
                app.imroValid.Text = 'OK';
                app.imroValid.FontColor = 'green';
            else
                app.imro_specified = false;
                app.imroValid.Text = 'invalid';
                app.imroValid.FontColor = 'black';
            end
            
            if all(app.raw_files_valid) && app.imro_specified
                app.RUNButton.Enable = true;
            else
                app.RUNButton.Enable = false;
            end
            
        end

        % Value changed function: OutputimroFileEditField
        function OutputimroFileEditFieldValueChanged(app, event)
            value = app.OutputimroFileEditField.Value;
            
            [direc, fname, ext] = fileparts(value);
            if exist(direc, 'dir')
                
                app.imroValid.Text = 'OK';
                app.imroValid.FontColor = 'green';
                
                app.imro_file_path = value;
                
                app.imro_specified = true;
                
                app.OutputimroFileEditField.Value = app.imro_file_path;
            else
                app.imro_specified = false;
                app.imroValid.Text = 'invalid';
                app.imroValid.FontColor = 'black';
            end
              
            if all(app.raw_files_valid) && app.imro_specified
                app.RUNButton.Enable = true;
            else
                app.RUNButton.Enable = false;
            end
        end

        % Value changed function: SavePlotsCheckBox
        function SavePlotsCheckBoxValueChanged(app, event)
            value = app.SavePlotsCheckBox.Value;
            if value == true
                app.PlotSaveDirEditField.Enable = true;
                app.PlotSaveDirEditFieldLabel.Enable = true;
                
                app.PlotSaveDirButton.Enable = true;
                
            else
                app.PlotSaveDirEditField.Enable = false;
                app.PlotSaveDirEditFieldLabel.Enable = false;
                app.PlotSaveDirButton.Enable = false;
            end
        end

        % Button pushed function: PlotSaveDirButton
        function PlotSaveDirButtonPushed(app, event)
            %[file, path] = uiputfile('*', 'Save output .imro file as');
            app.plot_save_dir = uigetdir();
            app.plot_save_dir_specified = true;
            
            app.PlotSaveDirEditField.Value = app.plot_save_dir;
            
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            cleanupObj = onCleanup(@app.sigint_handler);
            
            set(app.RUNButton, 'Text', 'Running...');
            set(app.RUNButton, 'Enable', 'off');
            drawnow();
            
            ops.do_classifier = app.DoClassificationCheckBox.Value;
            
            ops.banks_to_validate = 1:2;   % restricts classification validation to
                                           % deepest 2 banks, as used in the paper.
            
            ops.save_plot = app.plot_save_dir_specified && app.SavePlotsCheckBox.Value;            % plot optimization summary
            
            ops.plot_dir = app.plot_save_dir; % plot save directory  
            
            ops.do_plot = true;
            
            ops.nspikes_per_unit = app.NumSpikesUnitEditField.Value;
                                      
            switch app.AlgorithmDropDown.Value
                case 'CBS'
                    ops.selection_method = 'cbs';
                case 'uCBS'
                    ops.selection_method = 'mucbs';
            end
            
            switch app.InitMethodDropDown.Value
                case 'uCBS'
                    ops.init_method = 'mucbs';
                case 'Checker'
                    ops.init_method = 'checker';
                case 'Line' 
                    ops.init_method = 'line';                        
            end
                 
            app.bank_models = get_bank_model_neuropixel(app.raw_files, ops);
         
            
            selection_map = optimize_selection_map(app.bank_models, ops);
            
            %%%%%
            %%%%% write output map to an .imro file 
            %%%%% 
            
            if app.imro_specified
                write_cbs_imro_file(app.imro_file_path, selection_map, app.bank_models);
            end
            
            set(app.RUNButton, 'Text', 'Run');
            set(app.RUNButton, 'Enable', 'on');
            drawnow();
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            url = 'https://github.com/pesaranlab/neuro_cbs';
            web(url);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CBSGraphicalInterfaceUIFigure = uifigure('Visible', 'off');
            app.CBSGraphicalInterfaceUIFigure.Name = 'CBS Graphical Interface';
            app.GridLayout = uigridlayout(app.CBSGraphicalInterfaceUIFigure);
            app.DoClassificationCheckBox = uicheckbox(app.GridLayout);
            app.DoClassificationCheckBox.Tooltip = {'Run classification validation initially and after every pass.'};
            app.DoClassificationCheckBox.Text = 'Do Classification';
            app.SavePlotsCheckBox = uicheckbox(app.GridLayout);
            app.SavePlotsCheckBox.ValueChangedFcn = createCallbackFcn(app, @SavePlotsCheckBoxValueChanged, true);
            app.SavePlotsCheckBox.Tooltip = {'Whether or not to save generated plots.'};
            app.SavePlotsCheckBox.Text = 'Save Plots';
            app.Bank0Button = uibutton(app.GridLayout, 'push');
            app.Bank0Button.ButtonPushedFcn = createCallbackFcn(app, @Bank0ButtonPushed, true);
            app.Bank0Button.Icon = 'Open-Folder-512.png';
            app.Bank0Button.Text = '';
            app.Bank1Button = uibutton(app.GridLayout, 'push');
            app.Bank1Button.ButtonPushedFcn = createCallbackFcn(app, @Bank1ButtonPushed, true);
            app.Bank1Button.Icon = 'Open-Folder-512.png';
            app.Bank1Button.Text = '';
            app.Bank2Button = uibutton(app.GridLayout, 'push');
            app.Bank2Button.ButtonPushedFcn = createCallbackFcn(app, @Bank2ButtonPushed, true);
            app.Bank2Button.Icon = 'Open-Folder-512.png';
            app.Bank2Button.Text = '';
            app.ImroButton = uibutton(app.GridLayout, 'push');
            app.ImroButton.ButtonPushedFcn = createCallbackFcn(app, @ImroButtonPushed, true);
            app.ImroButton.Icon = 'Open-Folder-512.png';
            app.ImroButton.Text = '';
            app.PlotSaveDirButton = uibutton(app.GridLayout, 'push');
            app.PlotSaveDirButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSaveDirButtonPushed, true);
            app.PlotSaveDirButton.Icon = 'Open-Folder-512.png';
            app.PlotSaveDirButton.Enable = 'off';
            app.PlotSaveDirButton.Text = '';
            app.Bank0FileEditFieldLabel = uilabel(app.GridLayout);
            app.Bank0FileEditFieldLabel.Text = 'Bank 0 File';
            app.Bank0FileEditField = uieditfield(app.GridLayout, 'text');
            app.Bank0FileEditField.ValueChangedFcn = createCallbackFcn(app, @Bank0FileEditFieldValueChanged, true);
            app.Bank0FileEditField.Tooltip = {'Path to recording file for the dense bank 0 recording. Must be a *.ap.bin file, and the recording must be spike-sorted using Kilosort2 and the results saved in-place.'; ''};
            app.Bank0FileEditField.Value = '../data/rec_bank0/rec_bank0_imec0/rec_bank0_t0.imec0.ap.bin';
            app.Bank1FileEditFieldLabel = uilabel(app.GridLayout);
            app.Bank1FileEditFieldLabel.Text = 'Bank 1 File';
            app.Bank1FileEditField = uieditfield(app.GridLayout, 'text');
            app.Bank1FileEditField.ValueChangedFcn = createCallbackFcn(app, @Bank1FileEditFieldValueChanged, true);
            app.Bank1FileEditField.Tooltip = {'Path to recording file for the dense bank 1 recording. Must be a *.ap.bin file, and the recording must be spike-sorted using Kilosort2 and the results saved in-place.'; ''};
            app.Bank1FileEditField.Value = '../data/rec_bank1/rec_bank1_imec0/rec_bank1_t0.imec0.ap.bin';
            app.ProbeTypeDropDownLabel = uilabel(app.GridLayout);
            app.ProbeTypeDropDownLabel.Text = {'Probe Type'; ''};
            app.ProbeTypeDropDown = uidropdown(app.GridLayout);
            app.ProbeTypeDropDown.Items = {'Neuropixel 1.0 (phase 3b)', '', ''};
            app.ProbeTypeDropDown.Value = 'Neuropixel 1.0 (phase 3b)';
            app.Bank2FileEditFieldLabel = uilabel(app.GridLayout);
            app.Bank2FileEditFieldLabel.Text = 'Bank 2 File';
            app.Bank2FileEditField = uieditfield(app.GridLayout, 'text');
            app.Bank2FileEditField.ValueChangedFcn = createCallbackFcn(app, @Bank2FileEditFieldValueChanged, true);
            app.Bank2FileEditField.Tooltip = {'Path to recording file for the dense bank 2 recording. Must be a *.ap.bin file, and the recording must be spike-sorted using Kilosort2 and the results saved in-place.'; ''};
            app.Bank2FileEditField.Value = '../data/rec_bank2/rec_bank2_imec0/rec_bank2_t0.imec0.ap.bin';
            app.OutputimroFileLabel = uilabel(app.GridLayout);
            app.OutputimroFileLabel.Text = 'Output (imro) File';
            app.OutputimroFileEditField = uieditfield(app.GridLayout, 'text');
            app.OutputimroFileEditField.ValueChangedFcn = createCallbackFcn(app, @OutputimroFileEditFieldValueChanged, true);
            app.OutputimroFileEditField.Tooltip = {'Save resulting selection map (.imro file) as: '};
            app.OutputimroFileEditField.Value = '../data/output.imro';
            app.AlgorithmDropDownLabel = uilabel(app.GridLayout);
            app.AlgorithmDropDownLabel.Text = 'Algorithm';
            app.AlgorithmDropDown = uidropdown(app.GridLayout);
            app.AlgorithmDropDown.Items = {'CBS', 'uCBS'};
            app.AlgorithmDropDown.Tooltip = {'Selection algorithm to use'};
            app.AlgorithmDropDown.Value = 'CBS';
            app.NumSpikesUnitEditFieldLabel = uilabel(app.GridLayout);
            app.NumSpikesUnitEditFieldLabel.Text = 'Num. Spikes/Unit';
            app.NumSpikesUnitEditField = uieditfield(app.GridLayout, 'numeric');
            app.NumSpikesUnitEditField.Tooltip = {'Number of spikes to use for electrode selection. Neurons that did not spike >= this number of times will be excluded.'};
            app.NumSpikesUnitEditField.Value = 100;
            app.PlotSaveDirEditFieldLabel = uilabel(app.GridLayout);
            app.PlotSaveDirEditFieldLabel.Enable = 'off';
            app.PlotSaveDirEditFieldLabel.Text = 'Plot Save Dir.';
            app.PlotSaveDirEditField = uieditfield(app.GridLayout, 'text');
            app.PlotSaveDirEditField.Enable = 'off';
            app.InitMethodDropDownLabel = uilabel(app.GridLayout);
            app.InitMethodDropDownLabel.Text = 'Init. Method';
            app.InitMethodDropDown = uidropdown(app.GridLayout);
            app.InitMethodDropDown.Items = {'Checker', 'Line', 'uCBS'};
            app.InitMethodDropDown.Tooltip = {'How to initialize the selection map'};
            app.InitMethodDropDown.Value = 'Checker';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = {''; ''};
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.GridLayout);
            app.Label_3.Text = {''; ''};
            app.RUNButton = uibutton(app.GridLayout, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Enable = 'off';
            app.RUNButton.Text = {'RUN'; ''};
            app.imroValid = uilabel(app.GridLayout);
            app.imroValid.Text = '';
            app.HelpButton = uibutton(app.GridLayout, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            % Show the figure after all components are created
            app.CBSGraphicalInterfaceUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = neuro_cbs_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CBSGraphicalInterfaceUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CBSGraphicalInterfaceUIFigure)
        end
    end
end